classdef EDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlotMenu_2                 matlab.ui.container.Menu
        LinesizeMenu               matlab.ui.container.Menu
        thinMenu                   matlab.ui.container.Menu
        mediumMenu                 matlab.ui.container.Menu
        thickMenu                  matlab.ui.container.Menu
        exportMenu                 matlab.ui.container.Menu
        NameEditFieldLabel         matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        PremisesEditFieldLabel     matlab.ui.control.Label
        PremisesEditField          matlab.ui.control.NumericEditField
        VotersEditFieldLabel       matlab.ui.control.Label
        VotersEditField            matlab.ui.control.NumericEditField
        CorrelationEditFieldLabel  matlab.ui.control.Label
        CorrelationEditField       matlab.ui.control.NumericEditField
        ConclusionEditFieldLabel   matlab.ui.control.Label
        ConclusionEditField        matlab.ui.control.EditField
        IterationsEditFieldLabel   matlab.ui.control.Label
        IterationsEditField        matlab.ui.control.NumericEditField
        NewSimulationButton        matlab.ui.control.Button
        SimulationsListBox         matlab.ui.control.ListBox
        SaveButton                 matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        DeleteButton               matlab.ui.control.Button
        PremiseDropDownLabel       matlab.ui.control.Label
        PremiseDropDown            matlab.ui.control.DropDown
        ProbabilityEditFieldLabel  matlab.ui.control.Label
        ProbabilityEditField       matlab.ui.control.NumericEditField
        ScenarioDropDownLabel      matlab.ui.control.Label
        ScenarioDropDown           matlab.ui.control.DropDown
        PlotLabel                  matlab.ui.control.Label
        SimulationDataLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        CBPbluePBPredLabel         matlab.ui.control.Label
        pStepsizeButtonGroup       matlab.ui.container.ButtonGroup
        Button                     matlab.ui.control.RadioButton
        Button_2                   matlab.ui.control.RadioButton
        Button_3                   matlab.ui.control.RadioButton
        Button_4                   matlab.ui.control.RadioButton
        SimulationsLabel           matlab.ui.control.Label
        LegendCheckBox             matlab.ui.control.CheckBox
    end

     properties (Access = private)
        % Description
        name
        premises
        voters
        resultRelation
        runs
        corr
        pIterations
        premiseProbabilities
        
        % Stores Items for Dropdownlist and makes them available for the
        % class
        ItemsPlot
        ItemsData_Plot
        ItemsPremises
        Items_Simulations
        ItemsData_Simulations
        
        %auxiliary variables
        scens
        simCounter
        % Scenario Strings for Dropdownlist
        Index
        
        %Property Simulation
        Sim
        %Structure-Array in which done ore loaded simulations are stored at runtime
        Simulations
        %Simulations setting priors       
        premiseDropdownIndex
        %Index of plotted scenario
        % Weighted Aggregation has Index scens + 1
        plottedScen
        multiple_plots
        sim_plots
        selected_Simulations
        
        linewidth
        legend_fontsize
        leg
        leg_Visible
     end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setPath
            app.simCounter = 0;
            app.premiseProbabilities = zeros(1,26);
            
            app.premises = 0;
            app.PremisesEditField.Value = 0;
            
            app.voters = 0;
            app.VotersEditField.Value = 0;
            
            app.corr = 0;
            app.CorrelationEditField.Value = 0;
            
            app.runs = 1;
            app.IterationsEditField.Value = 1;
            
            app.premiseProbabilities = [];
            app.premiseDropdownIndex = 1;
            
            app.pStepsizeButtonGroup.SelectedObject = app.Button_2;
            app.pIterations = [0.01,0.02:0.02:0.98,0.99];      
            
            app.plottedScen = 1;
            app.linewidth = 1.5;
            app.legend_fontsize = 10;%12
            app.UIAxes.FontSize = 18;
            app.leg_Visible = 'on';
        end

        % Value changed function: PremisesEditField
        function PremisesEditFieldValueChanged(app, event)
            assert(app.PremisesEditField.Value <= 15);
            app.premises = app.PremisesEditField.Value;
            app.scens = 2^app.premises;      
            
            %Ensures that length of PremiseProbabilities vector is equal to
            %number of premises (Important when a new Simulation is startet
            %with less premises than the simulation before)
            if app.premises < length(app.premiseProbabilities)
               app.premiseProbabilities = [app.premiseProbabilities(1:app.premises)];
            end
            %If there are more premises than in the last executed
            %simulation, PriorProbabilities vector is adjusted in length
            %with default values 0
            if app.premises > length( app.premiseProbabilities)
                le = length(app.premiseProbabilities);
                app.premiseProbabilities(le+1:app.premises) = 0;
            end
                  
            %Generates Premise Dropdown for PriorProbability setting
            Men = ["A","B","C","D","E","F","G","H","I","J","K","L","M","N","O"];

            app.ItemsPremises = Men(1,1:app.premises);
            app.PremiseDropDown.Items = app.ItemsPremises;
            %Adds Index (A = 1. premise, B = 2. usw.)
            app.PremiseDropDown.ItemsData = (1:(app.premises));
            
            app.PremiseDropDown.Value = 1;
            app.premiseDropdownIndex = 1;
            app.ProbabilityEditField.Value = app.premiseProbabilities(1);
        end

        % Value changed function: VotersEditField
        function VotersEditFieldValueChanged(app, event)
            app.voters = app.VotersEditField.Value; 
        end

        % Value changed function: CorrelationEditField
        function CorrelationEditFieldValueChanged(app, event)
            app.corr = app.CorrelationEditField.Value;
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            app.runs = app.IterationsEditField.Value;
        end

        % Value changed function: ConclusionEditField
        function ConclusionEditFieldValueChanged(app, event)
            app.resultRelation = app.ConclusionEditField.Value;            
        end

        % Selection changed function: pStepsizeButtonGroup
        function pStepsizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.pStepsizeButtonGroup.SelectedObject;
            if selectedButton == app.Button
                app.pIterations = [0.01:0.01:0.99];
            end
            if selectedButton == app.Button_2
                app.pIterations = [0.01,0.02:0.02:0.98,0.99];
            end
            if selectedButton == app.Button_3
                app.pIterations = [0.01,0.05:0.05:0.95,0.99];
            end
            if selectedButton == app.Button_4
                app.pIterations = [0.01,0.1:0.1:0.9,0.99];
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if length(app.selected_Simulations) == 1
                export_name = string(app.Simulations(app.selected_Simulations).Sim.name);
                export_name = strcat(export_name, ".mat");
                Simulation = app.Simulations(app.selected_Simulations).Sim;
                uisave("Simulation", export_name);
                setPath
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            i = length(app.Items_Simulations)+1;
            %file = "";
            file = string(uigetfile);
            if file ~= "0"
                import = load(file);
                app.Simulations(i).Sim = import.Simulation;
                app.Items_Simulations{i} = app.Simulations(i).Sim.name;
                app.ItemsData_Simulations(i) = i;
                app.SimulationsListBox.Items = app.Items_Simulations;
                app.SimulationsListBox.ItemsData = app.ItemsData_Simulations;
            end
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name = app.NameEditField.Value;
        end

        % Value changed function: PremiseDropDown
        function PremiseDropDownValueChanged(app, event)
            %If Premises is selected the corresponding prior probability is
            %displayd in the edit field
            app.premiseDropdownIndex = app.PremiseDropDown.Value;
            app.ProbabilityEditField.Value = app.premiseProbabilities(app.premiseDropdownIndex);
        end

        % Value changed function: ProbabilityEditField
        function ProbabilityEditFieldValueChanged(app, event)
            %Edit-Feld-Value is saved at selcted index (Selection in PremiseDropDown) in vector PremiseProbabilities
            app.premiseProbabilities(app.premiseDropdownIndex) = app.ProbabilityEditField.Value;
            %empty legend data, Clear Figure
            l = [];
            app.UIAxes.cla;
  
            %Set Probabilities and calculate new Weighted Result
            for i = app.selected_Simulations;
                app.Simulations(i).Sim.setPriors(app.premiseProbabilities);
                app.Simulations(i).Sim.setScenarioWeights;
                app.Simulations(i).Sim.calcWeightedResult;
            end
            
            if app.plottedScen <=app.scens
                for k = 1:length(app.selected_Simulations)
                    i = app.selected_Simulations(k);
                    pl = plot(app.UIAxes, app.Simulations(i).Sim.pIterations,app.Simulations(i).Sim.Scenarios(app.plottedScen).SResult(:,1), app.Simulations(i).Sim.pIterations,app.Simulations(i).Sim.Scenarios(app.plottedScen).SResult(:,2));
                    pl(1).LineWidth = app.linewidth;
                    pl(2).LineWidth = app.linewidth;
                    nv = strcat("n=", string(app.Simulations(i).Sim.voters));
                    correl = strcat(",\rho=", string(app.Simulations(i).Sim.corr));
                    legstr = strcat(nv, correl);
                    l{k,1} = legstr;
                    l{k,2} = legstr;                   
                end
                %l=flipud(l);
                l = transpose(l);
                app.leg = legend(app.UIAxes, l,"Location","southeast","FontSize",app.legend_fontsize,"FontWeight", "normal");
                %app.leg.Visible = app.leg_Visible;
                set(app.leg,'visible',app.leg_Visible);            
            end
            if app.plottedScen == (app.scens+1)
                for k = 1:length(app.selected_Simulations)
                    i = app.selected_Simulations(k);
                    pl = plot(app.UIAxes, app.Simulations(i).Sim.pIterations,app.Simulations(i).Sim.Result(:,1), app.Simulations(i).Sim.pIterations, app.Simulations(i).Sim.Result(:,2));
                    pl(1).LineWidth = app.linewidth;
                    pl(2).LineWidth = app.linewidth;
                    nv = strcat("n=", string(app.Simulations(i).Sim.voters));
                    correl = strcat(",\rho=", string(app.Simulations(i).Sim.corr));
                    legstr = strcat(nv, correl);
                    l{k,1} = legstr;
                    l{k,2} = legstr;  
                end
                %l=flipud(l);
                l = transpose(l);
                app.leg = legend(app.UIAxes, l,"Location","southeast","FontSize",app.legend_fontsize,"FontWeight", "normal");
                set(app.leg,'visible',app.leg_Visible);
            end     
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
    	    %l = legend(app.UIAxes);
            l = [];
            value = app.ScenarioDropDown.Value;
            app.plottedScen = value;
            app.UIAxes.cla;
            
            if app.plottedScen <=app.scens
                for k = 1:length(app.selected_Simulations)
                    i = app.selected_Simulations(k);
                    pl = plot(app.UIAxes, app.Simulations(i).Sim.pIterations,app.Simulations(i).Sim.Scenarios(app.plottedScen).SResult(:,1), app.Simulations(i).Sim.pIterations,app.Simulations(i).Sim.Scenarios(app.plottedScen).SResult(:,2));
                    pl(1).LineWidth = app.linewidth;
                    pl(2).LineWidth = app.linewidth;
                    nv = strcat("n=", string(app.Simulations(i).Sim.voters));
                    correl = strcat(",\rho=", string(app.Simulations(i).Sim.corr));
                    legstr = strcat(nv, correl);
                    l{k,1} = legstr;
                    l{k,2} = legstr;  
                end
                %l=flipud(l);
                l = transpose(l);
                app.leg = legend(app.UIAxes, l,"Location","southeast","FontSize",app.legend_fontsize,"FontWeight", "normal");
                set(app.leg,'visible',app.leg_Visible);  
            end
            if app.plottedScen == (app.scens+1)
                for k = 1:length(app.selected_Simulations)
                    i = app.selected_Simulations(k);
                    pl = plot(app.UIAxes, app.Simulations(i).Sim.pIterations,app.Simulations(i).Sim.Result(:,1), app.Simulations(i).Sim.pIterations, app.Simulations(i).Sim.Result(:,2));
                    pl(1).LineWidth = app.linewidth;
                    pl(2).LineWidth = app.linewidth;
                    nv = strcat("n=", string(app.Simulations(i).Sim.voters));
                    correl = strcat(",\rho=", string(app.Simulations(i).Sim.corr));
                    legstr = strcat(nv, correl);
                    l{k,1} = legstr;
                    l{k,2} = legstr; 
                end
                %l=flipud(l);
                l = transpose(l);
                app.leg = legend(app.UIAxes, l,"Location","southeast","FontSize",app.legend_fontsize,"FontWeight", "normal");
                set(app.leg,'visible',app.leg_Visible);  
            end
        end

        % Value changed function: SimulationsListBox
        function SimulationsListBoxValueChanged(app, event)
            l = [];
            %Indices of Simulations
            app.selected_Simulations = app.SimulationsListBox.Value;
            app.UIAxes.cla;
            lg=length(app.selected_Simulations);
            if lg > 0
            %i ist Index des letzten Elements in selected_Simulation
            i = app.selected_Simulations(lg);
            
            %Check for non-empty selction
            if length(app.selected_Simulations) >= 1         
%%%%%%%%%%%%%%%%%%%%%  GUI UPDATE  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %i is Index of the last element in selected_Simulations
                i = length(app.selected_Simulations);
                app.name = app.Simulations(app.selected_Simulations(i)).Sim.name;
                app.NameEditField.Value = app.name;
            
                app.premises = app.Simulations(app.selected_Simulations(i)).Sim.premises;
                app.PremisesEditField.Value = app.premises;
            
                old_scens = app.scens;
                app.scens = app.Simulations(app.selected_Simulations(i)).Sim.scens;
                if old_scens == app.scens
                    compatible_Simulation = 1;
                else
                    compatible_Simulation = 0;
                end
            
                app.voters = app.Simulations(app.selected_Simulations(i)).Sim.voters;
                app.VotersEditField.Value = app.voters;
            
                app.corr = app.Simulations(app.selected_Simulations(i)).Sim.corr;
                app.CorrelationEditField.Value = app.corr;
            
                app.runs = app.Simulations(app.selected_Simulations(i)).Sim.runs;
                app.IterationsEditField.Value = app.runs;
                        
                app.resultRelation = app.Simulations(app.selected_Simulations(i)).Sim.resultRelation;
                app.ConclusionEditField.Value = app.resultRelation;
            
                app.premiseProbabilities = app.Simulations(app.selected_Simulations(i)).Sim.priors;
                app.ProbabilityEditField.Value = app.premiseProbabilities(app.premiseDropdownIndex);
            
                
                Men = ["A","B","C","D","E","F","G","H","I","J","K","L","M","N","O","P","Q","R","S","T","U","V","W","X","Y","Z"];
                app.ItemsPremises = Men(1,1:app.premises);
                app.PremiseDropDown.Items = app.ItemsPremises;
                app.PremiseDropDown.ItemsData = (1:(app.premises));
                app.premiseDropdownIndex = 1;
                             
                if compatible_Simulation == 1
                    app.ScenarioDropDown.Value = app.plottedScen;
                end
                if compatible_Simulation == 0
                    name = app.Simulations(app.selected_Simulations(i)).Sim.name
                    a = transpose(app.Simulations(app.selected_Simulations(i)).Sim.string_SMatrix)
                    app.ItemsPlot=transpose(app.Simulations(app.selected_Simulations(i)).Sim.string_SMatrix);
                    %Items are scenario strings, eg. (111|1) 
                    app.ScenarioDropDown.Items = app.ItemsPlot;
                    %Items Data liefert Index des Szenarios in SMatrix
                    app.ScenarioDropDown.ItemsData = transpose(1:(app.Simulations(app.selected_Simulations(i)).Sim.scens + 1));                    
                    app.plottedScen = (app.Simulations(app.selected_Simulations(i)).Sim.scens + 1);
                    app.ScenarioDropDown.Value = app.ScenarioDropDown.ItemsData(app.Simulations(app.selected_Simulations(i)).Sim.scens + 1);
                end                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% END GUI UPDATE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
                %Plot selcted simulation
                %Plot scenario
                if app.plottedScen <= app.scens
                    for k = 1:length(app.selected_Simulations)
                        i = app.selected_Simulations(k);
                        app.Simulations(i).Sim.setScenarioWeights;
                        app.Simulations(i).Sim.calcWeightedResult;
                        pl = plot(app.UIAxes, app.Simulations(i).Sim.pIterations,app.Simulations(i).Sim.Scenarios(app.plottedScen).SResult(:,1), app.Simulations(i).Sim.pIterations, app.Simulations(i).Sim.Scenarios(app.plottedScen).SResult(:,2));
                        pl(1).LineWidth = app.linewidth;
                        pl(2).LineWidth = app.linewidth;
                    nv = strcat("n=", string(app.Simulations(i).Sim.voters));
                    correl = strcat(",\rho=", string(app.Simulations(i).Sim.corr));
                    legstr = strcat(nv, correl);
                    l{k,1} = legstr;
                    l{k,2} = legstr;   
                    end
                    %l=flipud(l);
                    l = transpose(l);
                    app.leg = legend(app.UIAxes, l,"Location","southeast","FontSize",app.legend_fontsize,"FontWeight", "normal");
                    set(app.leg,'visible',app.leg_Visible);  
                end                
                %Plot Weighted Agrregation
                app.Simulations
                if app.plottedScen == (app.scens+1)
                    for k = 1:length(app.selected_Simulations)
                        i = app.selected_Simulations(k);
                
                        app.Simulations(i).Sim.setScenarioWeights;
                        app.Simulations(i).Sim.calcWeightedResult;
                        pl = plot(app.UIAxes, app.Simulations(i).Sim.pIterations,app.Simulations(i).Sim.Result(:,1), app.Simulations(i).Sim.pIterations, app.Simulations(i).Sim.Result(:,2));
                        pl(1).LineWidth = app.linewidth;
                        pl(2).LineWidth = app.linewidth;
                        nv = strcat("n=", string(app.Simulations(i).Sim.voters));
                        nv = strcat("n=", string(app.Simulations(i).Sim.voters));
                        correl = strcat(",\rho=", string(app.Simulations(i).Sim.corr));
                        legstr = strcat(nv, correl);
                        l{k,1} = legstr;
                        l{k,2} = legstr;   
                    end
                    %l=flipud(l);
                    l = transpose(l);
                    app.leg = legend(app.UIAxes, l,"Location","southeast","FontSize",app.legend_fontsize,"FontWeight", "normal");
                    set(app.leg,'visible',app.leg_Visible);  
                end
            end
            end
        end

        % Button pushed function: NewSimulationButton
        function NewSimulationButtonPushed(app, event)
            %Create simulation object
            app.Sim = Simulation(app.premises, app.voters, app.resultRelation, app.corr, app.runs, app.pIterations, app.name);
            app.Sim.setPriors(app.premiseProbabilities);
            %sumulate object "Sim"
            [app.Sim, finished] = app.Sim.simulate; 
            app.simCounter = app.simCounter + finished;
            i = length(app.Simulations) + 1;
            %This Block adds the Sim object to the Simulations List and
            %andels the Elements in ListBox 
            if finished == 1
                app.Simulations(i).Sim = app.Sim;
                app.Items_Simulations{i} = app.Simulations(i).Sim.name;
                app.ItemsData_Simulations(i) = i;
                app.SimulationsListBox.Items = app.Items_Simulations;
                app.SimulationsListBox.ItemsData = app.ItemsData_Simulations;
                app.ItemsPlot=transpose(app.Simulations(i).Sim.string_SMatrix);
                app.ScenarioDropDown.Items = app.ItemsPlot;
                app.ScenarioDropDown.ItemsData = transpose(1:(app.scens+1));
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if length(app.selected_Simulations) == 1
                %Deletes simulation from simulations list. Later Elements go up
                app.Simulations(app.selected_Simulations) = [];
                app.Items_Simulations(app.selected_Simulations) = [];
                %Deletes Item from Listbox ItemsData
                %ItemsData are Indizes for calls like app.Simulation(index from ItemsData). 
                %At Deletion: Indizes = [1,2,3,4] --> Indizes=[1,3,4] (Lower Elements go up) --> Indizes = %[1,2,3]
                app.ItemsData_Simulations(app.selected_Simulations) = [];
                app.ItemsData_Simulations(app.selected_Simulations:length(app.ItemsData_Simulations)) = app.selected_Simulations:length(app.ItemsData_Simulations);
                app.SimulationsListBox.Items = app.Items_Simulations;
                app.SimulationsListBox.ItemsData = app.ItemsData_Simulations;
                
                
                %attaches Items and ItemsData to ListBox
                app.SimulationsListBox.Items = app.Items_Simulations;
                app.SimulationsListBox.ItemsData = app.ItemsData_Simulations;
                app.UIAxes.cla;
                
                if length(app.Items_Simulations) == 1
                    app.SimulationsListBox.Value = [];
                    app.selected_Simulations = [];
                end
            end
        end

        % Menu selected function: thinMenu
        function thinMenuSelected(app, event)
            app.linewidth = 1;
            app.legend_fontsize = 11;
            app.UIAxes.FontSize = 18;
        end

        % Menu selected function: mediumMenu
        function mediumMenuSelected(app, event)
            app.linewidth = 1.5;
            app.legend_fontsize = 11;
            app.UIAxes.FontSize = 18;
        end

        % Menu selected function: thickMenu
        function thickMenuSelected(app, event)
            app.linewidth = 2;
            app.legend_fontsize = 11;
            app.UIAxes.FontSize = 18;
        end

        % Menu selected function: exportMenu
        function exportMenuSelected(app, event)
            app.linewidth = 2.5;
            app.legend_fontsize = 18;
            app.UIAxes.FontSize = 30;
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            value = app.LegendCheckBox.Value
            if isempty(app.leg)
                return
            end
            if value == true
                app.leg_Visible = 'on';
            else
                app.leg_Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotMenu_2 = uimenu(app.UIFigure);
            app.PlotMenu_2.Text = 'Plot';
            app.LinesizeMenu = uimenu(app.PlotMenu_2);
            app.LinesizeMenu.Text = 'Linesize';
            app.thinMenu = uimenu(app.LinesizeMenu);
            app.thinMenu.MenuSelectedFcn = createCallbackFcn(app, @thinMenuSelected, true);
            app.thinMenu.Text = 'thin';
            app.mediumMenu = uimenu(app.LinesizeMenu);
            app.mediumMenu.MenuSelectedFcn = createCallbackFcn(app, @mediumMenuSelected, true);
            app.mediumMenu.Text = 'medium';
            app.thickMenu = uimenu(app.LinesizeMenu);
            app.thickMenu.MenuSelectedFcn = createCallbackFcn(app, @thickMenuSelected, true);
            app.thickMenu.Text = 'thick';
            app.exportMenu = uimenu(app.LinesizeMenu);
            app.exportMenu.MenuSelectedFcn = createCallbackFcn(app, @exportMenuSelected, true);
            app.exportMenu.Text = 'export';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Tooltip = {'Name of Simulation'};
            app.NameEditField.Value = '-';
            app.PremisesEditFieldLabel = uilabel(app.UIFigure);
            app.PremisesEditFieldLabel.Text = 'Premises';
            app.PremisesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PremisesEditField.ValueChangedFcn = createCallbackFcn(app, @PremisesEditFieldValueChanged, true);
            app.PremisesEditField.Tooltip = {'Number of Premises'};
            app.VotersEditFieldLabel = uilabel(app.UIFigure);
            app.VotersEditFieldLabel.Text = 'Voters';
            app.VotersEditField = uieditfield(app.UIFigure, 'numeric');
            app.VotersEditField.ValueChangedFcn = createCallbackFcn(app, @VotersEditFieldValueChanged, true);
            app.VotersEditField.Tooltip = {'Number of Voters'};
            app.CorrelationEditFieldLabel = uilabel(app.UIFigure);
            app.CorrelationEditFieldLabel.Text = 'Correlation';
            app.CorrelationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CorrelationEditField.Limits = [-1 1];
            app.CorrelationEditField.ValueChangedFcn = createCallbackFcn(app, @CorrelationEditFieldValueChanged, true);
            app.CorrelationEditField.Tooltip = {'Correlations between Premises'};
            app.ConclusionEditFieldLabel = uilabel(app.UIFigure);
            app.ConclusionEditFieldLabel.Text = 'Conclusion';
            app.ConclusionEditField = uieditfield(app.UIFigure, 'text');
            app.ConclusionEditField.ValueChangedFcn = createCallbackFcn(app, @ConclusionEditFieldValueChanged, true);
            app.ConclusionEditField.Tooltip = {'Logic formula for the conclusion'};
            app.ConclusionEditField.Value = '-';
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Tooltip = {'Monte-Carlo iterations per p'};
            app.IterationsEditField.Value = 1;
            app.NewSimulationButton = uibutton(app.UIFigure, 'push');
            app.NewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NewSimulationButtonPushed, true);
            app.NewSimulationButton.Tooltip = {'Create and simulate new Simulation'};
            app.NewSimulationButton.Text = 'New Simulation';
            app.SimulationsListBox = uilistbox(app.UIFigure);
            app.SimulationsListBox.Items = {};
            app.SimulationsListBox.Multiselect = 'on';
            app.SimulationsListBox.ValueChangedFcn = createCallbackFcn(app, @SimulationsListBoxValueChanged, true);
            app.SimulationsListBox.Value = {};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save selcted simulation to file'};
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load simulation from file'};
            app.LoadButton.Text = 'Load';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Tooltip = {'Delete selected simulation from list'};
            app.DeleteButton.Text = 'Delete';
            app.PremiseDropDownLabel = uilabel(app.UIFigure);
            app.PremiseDropDownLabel.Text = 'Premise';
            app.PremiseDropDown = uidropdown(app.UIFigure);
            app.PremiseDropDown.Items = {};
            app.PremiseDropDown.ValueChangedFcn = createCallbackFcn(app, @PremiseDropDownValueChanged, true);
            app.PremiseDropDown.Tooltip = {'Select premise for its prior probability'};
            app.PremiseDropDown.Value = {};
            app.ProbabilityEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilityEditFieldLabel.Text = 'Probability';
            app.ProbabilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProbabilityEditField.Limits = [-1 1];
            app.ProbabilityEditField.ValueChangedFcn = createCallbackFcn(app, @ProbabilityEditFieldValueChanged, true);
            app.ProbabilityEditField.Tooltip = {'Prior Probabability of selected premise'};
            app.ScenarioDropDownLabel = uilabel(app.UIFigure);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.UIFigure);
            app.ScenarioDropDown.Items = {};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Tooltip = {'Plot selected scenario'};
            app.ScenarioDropDown.Value = {};
            app.PlotLabel = uilabel(app.UIFigure);
            app.PlotLabel.Text = 'Plot';
            app.SimulationDataLabel = uilabel(app.UIFigure);
            app.SimulationDataLabel.Text = 'Simulation Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'p')
            ylabel(app.UIAxes, 'P(M)')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.LineStyleOrder = {'-'; '-.'; ':'; '--'};
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.CBPbluePBPredLabel = uilabel(app.UIFigure);
            app.CBPbluePBPredLabel.Tooltip = {'Conclusion-based Procedure / Premise-based Procedure'};
            app.CBPbluePBPredLabel.Text = 'CBP (blue) / PBP (red)';
            app.pStepsizeButtonGroup = uibuttongroup(app.UIFigure);
            app.pStepsizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @pStepsizeButtonGroupSelectionChanged, true);
            app.pStepsizeButtonGroup.Tooltip = {'Sets stepsize for voter competence p'};
            app.pStepsizeButtonGroup.Title = 'p-Stepsize';
            app.Button = uiradiobutton(app.pStepsizeButtonGroup);
            app.Button.Tag = 'step1';
            app.Button.Text = '1%';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.pStepsizeButtonGroup);
            app.Button_2.Tag = 'step2';
            app.Button_2.Text = '2%';
            app.Button_3 = uiradiobutton(app.pStepsizeButtonGroup);
            app.Button_3.Tag = 'step5';
            app.Button_3.Text = '5%';
            app.Button_4 = uiradiobutton(app.pStepsizeButtonGroup);
            app.Button_4.Tag = 'step5';
            app.Button_4.Text = '10%';
            app.SimulationsLabel = uilabel(app.UIFigure);
            app.SimulationsLabel.Text = 'Simulations';
            app.LegendCheckBox = uicheckbox(app.UIFigure);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Tooltip = {'Disable Legend'};
            app.LegendCheckBox.Text = 'Legend';
            app.LegendCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end